classdef PR_ECGGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        AccquireButton     matlab.ui.control.StateButton
        HeartBeatCheckBox  matlab.ui.control.CheckBox
        FilterCheckBox     matlab.ui.control.CheckBox
        VoltageCheckBox    matlab.ui.control.CheckBox
        UIAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Property % Description
        TryToUseRealDAQ = 0
        sampleRate = 512;
        sesh
        filterObj
        winSize = 6;

        %data
        time = [];
        data = [];

        simNI_DAQ
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            setupTimeObj;
            N = 4;
            f1 = 0.1;
            f2 = 20;

            app.filterObj =designfilt('bandpassiir', 'FilterOrder', N, 'Halfpowerfrequency1',...
                f1, 'HalfPowerFrequency2', f2, 'SampleRate', app.sampleRate);
            
        end

        % Value changed function: AccquireButton
        function AccquireButtonValueChanged(app, event)
            value = app.AccquireButton.Value;
            if value == 1
                app.sesh.startBackground()
            else
                app.sesh.stop();
            end 
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.sesh.stop(); %stop the data aquisition if it is running
            activeTimers = timerfind; %find all running timer functions
            if ~isempty(activeTimers) %if there's still a timer running then
                stop(activeTimers); %stop all timers
                delete(activeTimers); %delete all timers
            end
            delete(app) %delete the app window
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.VoltageCheckBox = uicheckbox(app.UIFigure);
            app.VoltageCheckBox.Text = 'Voltage';
            app.FilterCheckBox = uicheckbox(app.UIFigure);
            app.FilterCheckBox.Text = 'Filter';
            app.HeartBeatCheckBox = uicheckbox(app.UIFigure);
            app.HeartBeatCheckBox.Text = 'Heart Beat';
            app.AccquireButton = uibutton(app.UIFigure, 'state');
            app.AccquireButton.ValueChangedFcn = createCallbackFcn(app, @AccquireButtonValueChanged, true);
            app.AccquireButton.Text = 'Accquire';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PR_ECGGui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end